classdef baseband_sampler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasebandsamplerUIFigure  matlab.ui.Figure
        OffsetSpinner            matlab.ui.control.Spinner
        OffsetSpinnerLabel       matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ser = serialport("COM4", 230400);

        raw=[];
        data=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.BasebandsamplerUIFigure,"center");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Text = "Running";
            app.StartButton.Enable = "off";

            write(app.ser, "x", "uint8");

            app.raw=read(app.ser, 19200*2, "uint8");

            for i=1:19200
                app.data(i)=app.raw(2*i-1)*256+app.raw(2*i);
            end

            cla(app.UIAxes);
            for i=app.OffsetSpinner.Value:20:19000
                plot(app.UIAxes, app.data(i:i+20+1), "y");
                hold(app.UIAxes, "on");
            end

            xlim(app.UIAxes, [1, 21]);
            ylim(app.UIAxes, [0, 65535]);

            assignin('base','data',app.data);

            app.StartButton.Text = "Start";
            app.StartButton.Enable = "on";
        end

        % Value changed function: OffsetSpinner
        function OffsetSpinnerValueChanged(app, event)
            value = app.OffsetSpinner.Value;
            
            if length(app.data)>0
                cla(app.UIAxes);
                for i=value:20:19000
                    plot(app.UIAxes, app.data(i:i+20+1), "y");
                    hold(app.UIAxes, "on");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasebandsamplerUIFigure = uifigure('Visible', 'off');
            app.BasebandsamplerUIFigure.Name = 'Baseband sampler';
            app.UIAxes = uiaxes(app.BasebandsamplerUIFigure);
            title(app.UIAxes, 'Eye diagram')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'ADC value')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.BasebandsamplerUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OffsetSpinnerLabel = uilabel(app.BasebandsamplerUIFigure);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.BasebandsamplerUIFigure);
            app.OffsetSpinner.Limits = [1 Inf];
            app.OffsetSpinner.ValueDisplayFormat = '%2d';
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @OffsetSpinnerValueChanged, true);
            app.OffsetSpinner.Value = 1;
            % Show the figure after all components are created
            app.BasebandsamplerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = baseband_sampler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasebandsamplerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasebandsamplerUIFigure)
        end
    end
end